classdef GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        ConfigNNTab                     matlab.ui.container.Tab
        NumoflayersSpinnerLabel         matlab.ui.control.Label
        NumoflayersSpinner              matlab.ui.control.Spinner
        TrainfunctionDropDownLabel      matlab.ui.control.Label
        TrainfunctionDropDown           matlab.ui.control.DropDown
        PerformancefunctionDropDownLabel  matlab.ui.control.Label
        PerformancefunctionDropDown     matlab.ui.control.DropDown
        StopconditionsPanel             matlab.ui.container.Panel
        epochsEditFieldLabel            matlab.ui.control.Label
        epochsEditField                 matlab.ui.control.EditField
        min_gradEditFieldLabel          matlab.ui.control.Label
        min_gradEditField               matlab.ui.control.EditField
        validationsEditFieldLabel       matlab.ui.control.Label
        validationsEditField            matlab.ui.control.EditField
        timeEditFieldLabel              matlab.ui.control.Label
        timeEditField                   matlab.ui.control.EditField
        AdvanceconfigurationPanel       matlab.ui.container.Panel
        learningrateEditFieldLabel      matlab.ui.control.Label
        learningrateEditField           matlab.ui.control.EditField
        momentumEditField_2Label        matlab.ui.control.Label
        momentumEditField               matlab.ui.control.EditField
        lambdaEditFieldLabel            matlab.ui.control.Label
        lambdaEditField                 matlab.ui.control.EditField
        sigmaEditFieldLabel             matlab.ui.control.Label
        sigmaEditField                  matlab.ui.control.EditField
        CreateButton                    matlab.ui.control.Button
        LayerstranferfunctionPanel      matlab.ui.container.Panel
        Layer1DropDown_7Label           matlab.ui.control.Label
        Layer1DropDown_1                matlab.ui.control.DropDown
        NumberofneuronsLabel            matlab.ui.control.Label
        Layer1_size                     matlab.ui.control.NumericEditField
        Layer2_size                     matlab.ui.control.NumericEditField
        Layer3_size                     matlab.ui.control.NumericEditField
        Layer2DropDownLabel             matlab.ui.control.Label
        Layer2DropDown                  matlab.ui.control.DropDown
        Layer3DropDownLabel             matlab.ui.control.Label
        Layer3DropDown                  matlab.ui.control.DropDown
        Layer4_size                     matlab.ui.control.NumericEditField
        Layer4DropDownLabel             matlab.ui.control.Label
        Layer4DropDown                  matlab.ui.control.DropDown
        DividefunctionDropDownLabel     matlab.ui.control.Label
        DividefunctionDropDown          matlab.ui.control.DropDown
        ModifyparmsButton               matlab.ui.control.Button
        trainRatio                      matlab.ui.control.NumericEditField
        valRatio                        matlab.ui.control.NumericEditField
        testRatio                       matlab.ui.control.NumericEditField
        TrainRatioLabel                 matlab.ui.control.Label
        ValRatioLabel                   matlab.ui.control.Label
        TestRatioLabel                  matlab.ui.control.Label
        TrainnetworkTab                 matlab.ui.container.Tab
        SimulateTab                     matlab.ui.container.Tab
        Label                           matlab.ui.control.Label
        CurrentnetworkEditFieldLabel    matlab.ui.control.Label
        CurrentnetworkEditField         matlab.ui.control.EditField
        LoaddatasetButton               matlab.ui.control.Button
        DatasetEditFieldLabel           matlab.ui.control.Label
        DatasetEditField                matlab.ui.control.EditField
        AvailabledatasetsDropDownLabel  matlab.ui.control.Label
        AvailabledatasetsDropDown       matlab.ui.control.DropDown
        TargetEditFieldLabel            matlab.ui.control.Label
        TargetEditField                 matlab.ui.control.EditField
        TargetsDropDownLabel            matlab.ui.control.Label
        TargetsDropDown                 matlab.ui.control.DropDown
    end


    properties (Access = public)
        pathToXls = '.\DataSet\ClassificaccaoFolhas.xlsx'; % Description
    end
    properties (Access = public)
        imgSetActive % Description
    end
    properties (Access = public)
        dataInput % Description
        end
    properties (Access = public)
        dataTargetBin % Description
    end
    
      properties (Access = public)
        activeNet % Description
     end
      
      properties (Access = public)
        numLayers = 1 % Description
    end
    properties (Access = public)
        divideFcn% Description
    end
    properties (Access = public)
        trainFcn% Description
    end
    
    properties (Access = public)
        g_valRatio = 0.0% Description
        end
    properties (Access = public)
        g_testRatio = 0.0% Description
    end
    properties (Access = public)
        g_trainRatio = 0.0% Description
    end

      properties (Access = public)
        layer1_size = 10 % Description
    end

      properties (Access = public)
        layer2_size = 10% Description
    end

    properties (Access = public)
        layerFnc % Description
    end
    
    properties (Access = public)
        layersFnc = "" % Description
    end
        
    properties (Access = public)
        layerSize = 10 % Description
    end


    methods (Access = private)

        % Button pushed function: CreateButton
        function CreateButtonPushed(app, event)
            
            
            app.numLayers =  app.NumoflayersSpinner.Value;
            app.trainFcn = app.TrainfunctionDropDown
            
            app.divideFcn = app.DividefunctionDropDown.Value;
            app.g_trainRatio = app.trainRatio.Value;
            app.g_valRatio = app.valRatio.Value;
            app.g_testRatio = app.testRatio.Value;
                        
            
            if app.numLayers == 1
                app.layersFnc(1) = app.Layer1DropDown_1.Value;
                app.layersFnc(2) = app.Layer2DropDown.Value;
            elseif app.numLayers == 2
                app.layersFnc(1) = app.Layer1DropDown_1.Value;
                app.layersFnc(2) = app.Layer2DropDown.Value;
                app.layersFnc(3) = app.Layer3DropDown.Value;
            elseif app.numLayers == 3
                app.layersFnc(1) = app.Layer1DropDown_1.Value;
                app.layersFnc(2) = app.Layer2DropDown.Value;
                app.layersFnc(3) = app.Layer3DropDown.Value;
            end
            
            app.layersFnc(1) = app.Layer1DropDown_1.Value;
            app.layersFnc(2) = app.Layer2DropDown.Value;
            app.layersFnc(3) = app.Layer3DropDown.Value;
            app.layersFnc(4) = app.Layer4DropDown.Value;
            
            app.layerSize(1) = app.Layer1_size.Value;
            app.layerSize(2) = app.Layer2_size.Value;
            app.layerSize(3) = app.Layer3_size.Value;
            app.layerSize(4) = app.Layer4_size.Value;
            
            
            %configure network
            app.activeNet = initNN(app.layerFnc, app.layerSize);
            configNNTrain(app.activeNet, app.trainFcn, app.divideFcn, app.g_trainRatio, app.g_valRatio, app.g_testRatio);
            
             %advance configuration
            app.activeNet.trainParam.epochs         = app.epochsEditField.Value;;
            app.activeNet.trainParam.goal           = 0;
            app.activeNet.trainParam.max_fail       = app.validationsEditField.Value;
            app.activeNet.trainParam.min_grad       = app.min_gradEditField.Value;
            app.activeNet.trainParam.show           = 25;
            app.activeNet.trainParam.showCommandLine= false; 
            app.activeNet.trainParam.showWindow     = true;
            app.activeNet.trainParam.time           = app.timeEditField.Value;
            
             if strcmp(app.trainFcn, 'traingdx')
                app.activeNet.trainParam.lr             = app.learningrateEditField.Value;; % Learning rate % good results 0.0001;
                app.activeNet.trainParam.mc             = app.momentumEditField.Value;
             end
            if strcmp(app.trainFcn, 'trainscg')
                app.activeNet.trainParam.sigma          = app.lambdaEditField.Value; % 5.0e-5
                app.activeNet.trainParam.lambda         = app.sigmaEditField.Value; % 5.0e-7
            end
            
            
        end

        % Value changed function: DividefunctionDropDown
        function DividefunctionDropDownValueChanged(app, event)
            value = app.DividefunctionDropDown.Value;
            
            if ~strcmp(value, 'none')
                app.trainRatio.Enable = 'on';
                app.valRatio.Enable = 'on';
                app.testRatio.Enable = 'on';
            else
                app.trainRatio.Enable = 'off';
                app.valRatio.Enable = 'off';
                app.testRatio.Enable = 'off';  
            end
            
        end

        % Button pushed function: LoaddatasetButton
        function LoaddatasetButtonPushed(app, event)
            
            dataSet = app.AvailabledatasetsDropDown.Value;
            targets = app.TargetsDropDown.Value;
            dataTarget = 0;
           
            
            if dataSet == 'Leafs 1'
                app.imgSetActive = loadDataSets('.\DataSet\Folhas_1', app.pathToXls, 1, 'A2:B991');
                app.DatasetEditField.Value = dataSet;
            end
            
            if dataSet == 'Leafs 2'
                app.imgSetActive = loadDataSets('.\DataSet\Folhas_2', app.pathToXls, 1, 'A2:B991');
                app.DatasetEditField.Value = dataSet;
            end
                 
            if dataSet == 'Leafs 3'
               app.imgSetActive = loadDataSets('.\DataSet\Folhas_3', app.pathToXls, 1, 'A2:B991');
               app.DatasetEditField.Value = dataSet;
            end
            
            app.dataInput  = [app.imgSetActive.binData];
            
            if strcmp(targets, 'Species')
                dataTarget = [app.imgSetActive.speciesId];
                app.TargetEditField.Value = targets;
            end
            
            if strcmp(targets, 'SubSpecies')
                dataTarget = [app.imgSetActive.subSpeciesId];
                app.TargetEditField.Value = targets;
            end
            
            app.dataTargetBin = targetToBinary (dataTarget);


        end

        % Button pushed function: ModifyparmsButton
        function ModifyparmsButtonPushed(app, event)
            
        end

        % Value changed function: NumoflayersSpinner
        function NumoflayersSpinnerValueChanged(app, event)
            value = app.NumoflayersSpinner.Value;
            
            if value == 1
                app.Layer1DropDown_1.Enable = 'on';
                app.Layer2DropDown.Enable = 'on';
                app.Layer3DropDown.Enable = 'off';
                app.Layer4DropDown.Enable = 'off';
                app.Layer1_size.Enable = 'on';
                app.Layer2_size.Enable = 'on';
                app.Layer3_size.Enable = 'off';
                app.Layer4_size.Enable = 'off';
            elseif value == 2
                app.Layer1DropDown_1.Enable = 'on';
                app.Layer2DropDown.Enable = 'on';
                app.Layer3DropDown.Enable = 'on';
                app.Layer4DropDown.Enable = 'off';
                app.Layer1_size.Enable = 'on';
                app.Layer2_size.Enable = 'on';
                app.Layer3_size.Enable = 'on';
                app.Layer4_size.Enable = 'off';
            elseif value == 3
                 app.Layer1DropDown_1.Enable = 'on';
                app.Layer2DropDown.Enable = 'on';
                app.Layer3DropDown.Enable = 'on';
                app.Layer4DropDown.Enable = 'on';
                app.Layer1_size.Enable = 'on';
                app.Layer2_size.Enable = 'on';
                app.Layer3_size.Enable = 'on';
                app.Layer4_size.Enable = 'on';
            else
                app.Layer1DropDown_1.Enable = 'off';
                app.Layer2DropDown.Enable = 'off';
                app.Layer3DropDown.Enable = 'off';
                app.Layer4DropDown.Enable = 'off';
                app.Layer1_size.Enable = 'off';
                app.Layer2_size.Enable = 'off';
                app.Layer3_size.Enable = 'off';
                app.Layer4_size.Enable = 'off';
            end
       
        end

        % Value changing function: NumoflayersSpinner
        function NumoflayersSpinnerValueChanging(app, event)
            changingValue = event.Value;
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.ConfigNNTab = uitab(app.TabGroup);
            app.ConfigNNTab.Title = 'Config NN';
            app.NumoflayersSpinnerLabel = uilabel(app.ConfigNNTab);
            app.NumoflayersSpinnerLabel.Text = 'Num of  layers';
            app.NumoflayersSpinner = uispinner(app.ConfigNNTab);
            app.NumoflayersSpinner.ValueChangingFcn = createCallbackFcn(app, @NumoflayersSpinnerValueChanging, true);
            app.NumoflayersSpinner.ValueChangedFcn = createCallbackFcn(app, @NumoflayersSpinnerValueChanged, true);
            app.NumoflayersSpinner.Limits = [0 3];
            app.TrainfunctionDropDownLabel = uilabel(app.ConfigNNTab);
            app.TrainfunctionDropDownLabel.Text = 'Train function';
            app.TrainfunctionDropDown = uidropdown(app.ConfigNNTab);
            app.TrainfunctionDropDown.Items = {'traingdx', 'trainscg', 'trainoss', 'traingda', 'traingdm', 'trainrp', 'trainlm', 'traincgp', 'traincgf', 'traincgb', 'trainc', 'trainbu', 'trainbr', 'trainbfgc', 'trainbfg'};
            app.TrainfunctionDropDown.Value = 'traingdx';
            app.PerformancefunctionDropDownLabel = uilabel(app.ConfigNNTab);
            app.PerformancefunctionDropDownLabel.Text = 'Performance function';
            app.PerformancefunctionDropDown = uidropdown(app.ConfigNNTab);
            app.PerformancefunctionDropDown.Items = {'mse', 'Option 2', 'Option 3', 'Option 4'};
            app.PerformancefunctionDropDown.Value = 'mse';
            app.StopconditionsPanel = uipanel(app.ConfigNNTab);
            app.StopconditionsPanel.Title = 'Stop conditions';
            app.epochsEditFieldLabel = uilabel(app.StopconditionsPanel);
            app.epochsEditFieldLabel.Text = 'epochs';
            app.epochsEditField = uieditfield(app.StopconditionsPanel, 'text');
            app.epochsEditField.Value = '5000';
            app.min_gradEditFieldLabel = uilabel(app.StopconditionsPanel);
            app.min_gradEditFieldLabel.Text = 'min_grad';
            app.min_gradEditField = uieditfield(app.StopconditionsPanel, 'text');
            app.min_gradEditField.Value = '1.e-200';
            app.validationsEditFieldLabel = uilabel(app.StopconditionsPanel);
            app.validationsEditFieldLabel.Text = 'validations';
            app.validationsEditField = uieditfield(app.StopconditionsPanel, 'text');
            app.validationsEditField.Value = '1000000';
            app.timeEditFieldLabel = uilabel(app.StopconditionsPanel);
            app.timeEditFieldLabel.Text = 'time';
            app.timeEditField = uieditfield(app.StopconditionsPanel, 'text');
            app.timeEditField.Value = 'inf';
            app.AdvanceconfigurationPanel = uipanel(app.ConfigNNTab);
            app.AdvanceconfigurationPanel.Title = 'Advance configuration';
            app.learningrateEditFieldLabel = uilabel(app.AdvanceconfigurationPanel);
            app.learningrateEditFieldLabel.Text = 'learning rate';
            app.learningrateEditField = uieditfield(app.AdvanceconfigurationPanel, 'text');
            app.learningrateEditField.Value = '0.01';
            app.momentumEditField_2Label = uilabel(app.AdvanceconfigurationPanel);
            app.momentumEditField_2Label.Text = 'momentum';
            app.momentumEditField = uieditfield(app.AdvanceconfigurationPanel, 'text');
            app.momentumEditField.Value = '1.05';
            app.lambdaEditFieldLabel = uilabel(app.AdvanceconfigurationPanel);
            app.lambdaEditFieldLabel.Text = 'lambda';
            app.lambdaEditField = uieditfield(app.AdvanceconfigurationPanel, 'text');
            app.lambdaEditField.Value = '5.0e-7';
            app.sigmaEditFieldLabel = uilabel(app.AdvanceconfigurationPanel);
            app.sigmaEditFieldLabel.Text = 'sigma';
            app.sigmaEditField = uieditfield(app.AdvanceconfigurationPanel, 'text');
            app.sigmaEditField.Value = '5.0e-5';
            app.CreateButton = uibutton(app.ConfigNNTab, 'push');
            app.CreateButton.ButtonPushedFcn = createCallbackFcn(app, @CreateButtonPushed, true);
            app.CreateButton.Text = 'Create';
            app.LayerstranferfunctionPanel = uipanel(app.ConfigNNTab);
            app.LayerstranferfunctionPanel.Title = 'Layers tranfer function';
            app.Layer1DropDown_7Label = uilabel(app.LayerstranferfunctionPanel);
            app.Layer1DropDown_7Label.Text = 'Layer  1';
            app.Layer1DropDown_1 = uidropdown(app.LayerstranferfunctionPanel);
            app.Layer1DropDown_1.Items = {'logsig', 'tansig', 'softmax', 'compet', 'hardlim', 'hardlims', 'netinv', 'poslin', 'purelin', 'radbas', 'radbasn', 'satlin', 'satlins', 'tribas'};
            app.Layer1DropDown_1.Enable = 'off';
            app.Layer1DropDown_1.Value = 'logsig';
            app.NumberofneuronsLabel = uilabel(app.LayerstranferfunctionPanel);
            app.NumberofneuronsLabel.Text = 'Number of neurons';
            app.Layer1_size = uieditfield(app.LayerstranferfunctionPanel, 'numeric');
            app.Layer1_size.Limits = [0 Inf];
            app.Layer1_size.Enable = 'off';
            app.Layer1_size.Value = 10;
            app.Layer2_size = uieditfield(app.LayerstranferfunctionPanel, 'numeric');
            app.Layer2_size.Limits = [0 Inf];
            app.Layer2_size.Enable = 'off';
            app.Layer2_size.Value = 10;
            app.Layer3_size = uieditfield(app.LayerstranferfunctionPanel, 'numeric');
            app.Layer3_size.Limits = [0 Inf];
            app.Layer3_size.Enable = 'off';
            app.Layer3_size.Value = 10;
            app.Layer2DropDownLabel = uilabel(app.LayerstranferfunctionPanel);
            app.Layer2DropDownLabel.Text = 'Layer  2';
            app.Layer2DropDown = uidropdown(app.LayerstranferfunctionPanel);
            app.Layer2DropDown.Items = {'logsig', 'tansig', 'softmax', 'compet', 'hardlim', 'hardlims', 'netinv', 'poslin', 'purelin', 'radbas', 'radbasn', 'satlin', 'satlins', 'tribas'};
            app.Layer2DropDown.Enable = 'off';
            app.Layer2DropDown.Value = 'logsig';
            app.Layer3DropDownLabel = uilabel(app.LayerstranferfunctionPanel);
            app.Layer3DropDownLabel.Text = 'Layer  3';
            app.Layer3DropDown = uidropdown(app.LayerstranferfunctionPanel);
            app.Layer3DropDown.Items = {'logsig', 'tansig', 'softmax', 'compet', 'hardlim', 'hardlims', 'netinv', 'poslin', 'purelin', 'radbas', 'radbasn', 'satlin', 'satlins', 'tribas'};
            app.Layer3DropDown.Enable = 'off';
            app.Layer3DropDown.Value = 'logsig';
            app.Layer4_size = uieditfield(app.LayerstranferfunctionPanel, 'numeric');
            app.Layer4_size.Limits = [0 Inf];
            app.Layer4_size.Enable = 'off';
            app.Layer4_size.Value = 10;
            app.Layer4DropDownLabel = uilabel(app.LayerstranferfunctionPanel);
            app.Layer4DropDownLabel.Text = 'Layer  4';
            app.Layer4DropDown = uidropdown(app.LayerstranferfunctionPanel);
            app.Layer4DropDown.Items = {'logsig', 'tansig', 'softmax', 'compet', 'hardlim', 'hardlims', 'netinv', 'poslin', 'purelin', 'radbas', 'radbasn', 'satlin', 'satlins', 'tribas'};
            app.Layer4DropDown.Enable = 'off';
            app.Layer4DropDown.Value = 'logsig';
            app.DividefunctionDropDownLabel = uilabel(app.ConfigNNTab);
            app.DividefunctionDropDownLabel.Text = 'Divide function';
            app.DividefunctionDropDown = uidropdown(app.ConfigNNTab);
            app.DividefunctionDropDown.Items = {'none', 'dividerand', 'divideblock', 'divideint', 'divideind'};
            app.DividefunctionDropDown.ValueChangedFcn = createCallbackFcn(app, @DividefunctionDropDownValueChanged, true);
            app.DividefunctionDropDown.Value = 'none';
            app.ModifyparmsButton = uibutton(app.ConfigNNTab, 'push');
            app.ModifyparmsButton.ButtonPushedFcn = createCallbackFcn(app, @ModifyparmsButtonPushed, true);
            app.ModifyparmsButton.Text = 'Modify parms';
            app.trainRatio = uieditfield(app.ConfigNNTab, 'numeric');
            app.trainRatio.Limits = [0 Inf];
            app.trainRatio.Enable = 'off';
            app.trainRatio.Value = 0.7;
            app.valRatio = uieditfield(app.ConfigNNTab, 'numeric');
            app.valRatio.Limits = [0 Inf];
            app.valRatio.Enable = 'off';
            app.valRatio.Value = 0.15;
            app.testRatio = uieditfield(app.ConfigNNTab, 'numeric');
            app.testRatio.Limits = [0 Inf];
            app.testRatio.Enable = 'off';
            app.testRatio.Value = 0.15;
            app.TrainRatioLabel = uilabel(app.ConfigNNTab);
            app.TrainRatioLabel.Text = 'Train Ratio';
            app.ValRatioLabel = uilabel(app.ConfigNNTab);
            app.ValRatioLabel.Text = 'Val Ratio';
            app.TestRatioLabel = uilabel(app.ConfigNNTab);
            app.TestRatioLabel.Text = 'Test Ratio';
            app.TrainnetworkTab = uitab(app.TabGroup);
            app.TrainnetworkTab.Title = 'Train network';
            app.SimulateTab = uitab(app.TabGroup);
            app.SimulateTab.Title = 'Simulate';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '';
            app.CurrentnetworkEditFieldLabel = uilabel(app.UIFigure);
            app.CurrentnetworkEditFieldLabel.Text = 'Current network';
            app.CurrentnetworkEditField = uieditfield(app.UIFigure, 'text');
            app.CurrentnetworkEditField.Editable = 'off';
            app.LoaddatasetButton = uibutton(app.UIFigure, 'push');
            app.LoaddatasetButton.ButtonPushedFcn = createCallbackFcn(app, @LoaddatasetButtonPushed, true);
            app.LoaddatasetButton.Text = 'Load data set';
            app.DatasetEditFieldLabel = uilabel(app.UIFigure);
            app.DatasetEditFieldLabel.Text = 'Data set';
            app.DatasetEditField = uieditfield(app.UIFigure, 'text');
            app.DatasetEditField.Editable = 'off';
            app.AvailabledatasetsDropDownLabel = uilabel(app.UIFigure);
            app.AvailabledatasetsDropDownLabel.Text = 'Available data sets';
            app.AvailabledatasetsDropDown = uidropdown(app.UIFigure);
            app.AvailabledatasetsDropDown.Items = {'Leafs 1', 'Leafs 2', 'Leafs 3'};
            app.AvailabledatasetsDropDown.Value = 'Leafs 1';
            app.TargetEditFieldLabel = uilabel(app.UIFigure);
            app.TargetEditFieldLabel.Text = 'Target';
            app.TargetEditField = uieditfield(app.UIFigure, 'text');
            app.TargetEditField.Editable = 'off';
            app.TargetsDropDownLabel = uilabel(app.UIFigure);
            app.TargetsDropDownLabel.Text = 'Targets';
            app.TargetsDropDown = uidropdown(app.UIFigure);
            app.TargetsDropDown.Items = {'Species', 'SubSpecies'};
            app.TargetsDropDown.Value = 'Species';
        end
    end

    methods (Access = public)

        % Construct app
        function app = GUI

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end